classdef detectorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        Label                     matlab.ui.control.Label
        TrainButton               matlab.ui.control.Button
        FruitRottenDetectorLabel  matlab.ui.control.Label
        ClassifyButton            matlab.ui.control.Button
        LoadImageButton           matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path            % The path of the image to classify
        trainedModel    % The trained model
        bag             % The bag of features
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
           app.Label.Text = "";             %Reset prediction
           app.Image.Visible = 'off';       %Get rid of image
           drawnow;                         %Update UI
           app.UIFigure.Visible = 'off';    %Hide to prevent double click & to bring focus to new window  
           [filename,filepath] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;'}, 'Select File to Open');
           app.UIFigure.Visible = 'on';             %Show window again
           app.path = strcat(filepath,filename);    %Put path together
           ImageFile = imread(app.path);            %Read image
           imagesc(app.UIAxes, ImageFile);          %Show image
           app.ClassifyButton.Visible='on';         %Enable classify
        end

        % Button pushed function: TrainButton
        function TrainModelButtonPushed(app, event)
            app.TrainButton.Visible='off';          %No need to re-train
            app.Label.Text = "Please wait, training model...";
            drawnow;                                    %Update UI
            [app.trainedModel, app.bag] = trainModel(); %Train model
            app.Label.Text = "Model trained";           %Update lable
            app.LoadImageButton.Visible='on';           %Enable load image
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            prediction = classifyFruit(app.path, app.trainedModel, app.bag); 
            disp(prediction);   
            app.Label.Text = prediction;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Visible = 'off';
            app.LoadImageButton.Text = 'Load Image';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Visible = 'off';
            app.ClassifyButton.Text = 'Classify';
            app.FruitRottenDetectorLabel = uilabel(app.UIFigure);
            app.FruitRottenDetectorLabel.Text = 'Fruit Rotten Detector';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'cover.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detectorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end